classdef graf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        Button_3                    matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        Button                      matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        VoltajeEditFieldLabel       matlab.ui.control.Label
        VoltajeEditField            matlab.ui.control.NumericEditField
        Button_4                    matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        Image3                      matlab.ui.control.Image
        SupervisorPanel             matlab.ui.container.Panel
        TiempoSliderLabel           matlab.ui.control.Label
        TiempoSlider                matlab.ui.control.Slider
        UIAxes                      matlab.ui.control.UIAxes
        ContraseaEditFieldLabel     matlab.ui.control.Label
        ContraseaEditField          matlab.ui.control.EditField
        FormaButtonGroup            matlab.ui.container.ButtonGroup
        ManualButton                matlab.ui.control.RadioButton
        AutomaticoButton            matlab.ui.control.RadioButton
        ObjetoButton                matlab.ui.control.RadioButton
        Image4                      matlab.ui.control.Image
        EditField                   matlab.ui.control.EditField
        TiempodeesperaSpinnerLabel  matlab.ui.control.Label
        TiempodeesperaSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        Properties=0
        v=3
        te=2
        %v = -((25.907)*(value)^2-294.46*value+1021.3);
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button_3, ContraseaEditField, 
        % FormaButtonGroup, TiempoSlider, TiempodeesperaSpinner, 
        % VoltajeEditField
        function Start(app, event)
            app.Lamp.Color = [0.149 1 0];
            app.Button.Enable = 'on';
            app.Button_2.Enable = 'on';
            app.ContraseaEditField.Enable="on";
            Dm=800;
            Jm=700;
            Kb=2;
            Kt=5;
            Ra=1;
            assignin("base","Dm",Dm)
            assignin("base","Kb",Kb)
            assignin("base","Jm",Jm)
            assignin("base","Kt",Kt)
            assignin("base","Ra",Ra)
            if app.ManualButton.Value == true
                app.Button.Enable=true;
                app.Button_2.Enable=true;
                app.Properties=0;
            elseif app.AutomaticoButton.Value == true
                app.Button.Enable=true;
                app.Button_2.Enable=false;
                app.Properties=1;
                app.UIAxes.XLim = [0 6];
                app.UIAxes.YLim = [0 6];
            else
                app.Button.Enable=true;
                app.Button_2.Enable=false;
                app.Properties=2;
                app.UIAxes.XLim = [0 6];
                app.UIAxes.YLim = [0 6];
            end
            pas=str2double(app.ContraseaEditField.Value);
            if pas==420
               app.Lamp.Color = [1 1 0];
               app.TiempoSlider.Enable = 'on';
               app.TiempodeesperaSpinner.Enable="on";
            else
               app.Lamp.Color = [0.149 1 0];
               app.TiempoSlider.Enable = 'off';
               app.TiempodeesperaSpinner.Enable="off";
            end
            app.te=app.TiempodeesperaSpinner.Value;
            app.v=abs(app.TiempoSlider.Value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes)
            app.Image4.Visible="off";
            app.EditField.Visible='off';
            voltaje= (25.907*(app.v)^2-294.46*app.v+1021.3);
            
            assignin("base","voltaje",voltaje)
            simout=sim("Motor");
            y=app.Image2.Position(2);
            
            app.VoltajeEditField.Value=voltaje;
            
            t=simout.Varposition.time;
            x=simout.Varposition.data;
            h=animatedline(app.UIAxes);
            dy2=abs(325-y)/sum(x(:) ~= 5);
            %manual
            if app.Properties==0
                for k=1:sum(x(:) ~= 5)
                    addpoints(h,t(k),x(k));
                    drawnow; pause(0.1)
                    y=y+dy2;
                    app.Image2.Position = [2 y 341 236];          
                end

            %automatico
            elseif app.Properties==1
                for k=1:sum(x(:) ~= 5)+1
                    addpoints(h,t(k),x(k));
                    drawnow; pause(0.1)
                    y=y+dy2;
                    app.Image2.Position = [2 y 341 236];          
                end
                tt=linspace(app.v,(app.v+app.te),5);
                for k=1:length(tt)
                    addpoints(h,tt(k),x(length(x)));
                    drawnow; pause(0.1)
                end
                    
                s=-1;
                voltaje=s*voltaje;
                assignin("base","voltaje",voltaje)
                simout=sim("Motor");
                y=app.Image2.Position(2);
                voltaje=s*voltaje;
                assignin("base","voltaje",voltaje)
    
                
                t=simout.Varposition.time+app.v+app.te;
                x=simout.Varposition.data;
                h=animatedline(app.UIAxes);
                dy=abs(144-y)/sum(x(:) ~= 0);
    
                for k=1:sum(x(:) ~= 0)+1
                    addpoints(h,t(k),x(k));
                    drawnow; pause(0.1)
                    y=y-dy;
                    app.Image2.Position = [2 y 341 236];
                end
            %objeto
            else
                for k=1:sum(x(:) ~= 5)+1
                    addpoints(h,t(k),x(k));
                    drawnow; pause(0.1)
                    y=y+dy2;
                    app.Image2.Position = [2 y 341 236];          
                end
                tt=linspace(app.v,app.v+app.te);
                for k=length(tt)
                    addpoints(h,tt(k),x(length(x)));
                    drawnow; pause(0.5)
                end
                    
                s=-1;
                voltaje=s*voltaje;
                assignin("base","voltaje",voltaje)
                simout=sim("Motor");
                y=app.Image2.Position(2);
                voltaje=s*voltaje;
                assignin("base","voltaje",voltaje)
    
                
                ta=simout.Varposition.time+app.v+app.te;
                xa=simout.Varposition.data;
                h=animatedline(app.UIAxes);
                dy=abs(144-y)/sum(xa(:) ~= 0);
    
                for k=1:(sum(xa(:) ~= 0)+1)/2
                    addpoints(h,ta(k),xa(k));
                    drawnow; pause(0.1)
                    y=y-dy;
                    app.Image2.Position = [2 y 341 236];
                end
                app.Image4.Visible="on";
                app.EditField.Visible='on';
                
                for k=1:sum(x(:) ~= 5)
                        addpoints(h,t(k),x(k));
                        drawnow; pause(0.1)
                        y=y+dy2;
                        app.Image2.Position = [2 y 341 236];          
                end

            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            cla(app.UIAxes)
            clearpoints
            app.Image4.Visible="off";
            app.EditField.Visible='off';
            s=-1;
            voltaje= (25.907*(app.v)^2-294.46*app.v+1021.3);
            voltaje=s*voltaje;
            assignin("base","voltaje",voltaje)
            simout=sim("Motor");
            y=app.Image2.Position(2);
            voltaje=s*voltaje;
            assignin("base","voltaje",voltaje)
            app.VoltajeEditField.Value=voltaje;
            
            t=simout.Varposition.time;
            x=simout.Varposition.data;
            h=animatedline(app.UIAxes);
            dy=abs(144-y)/sum(x(:) ~= 0);

            for k=1:sum(x(:) ~= 0)+1
                addpoints(h,t(k),x(k));
                drawnow; pause(0.1)
                y=y-dy;
                app.Image2.Position = [2 y 341 236];
         
            end
        
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            pause
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Start, true);
            app.Button_3.Icon = 'boton-start-oscuro.png';
            app.Button_3.Text = '';
            app.Lamp = uilamp(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'boton-subir.png';
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'boton-abajo.png';
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.VoltajeEditFieldLabel = uilabel(app.UIFigure);
            app.VoltajeEditFieldLabel.Text = 'Voltaje';
            app.VoltajeEditField = uieditfield(app.UIFigure, 'numeric');
            app.VoltajeEditField.ValueChangedFcn = createCallbackFcn(app, @Start, true);
            app.VoltajeEditField.Editable = 'off';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'stop.png';
            app.Button_4.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ensa.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'cortina.png';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'tubo.png';
            app.SupervisorPanel = uipanel(app.UIFigure);
            app.SupervisorPanel.Title = 'Supervisor';
            app.TiempoSliderLabel = uilabel(app.SupervisorPanel);
            app.TiempoSliderLabel.Enable = 'off';
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.SupervisorPanel);
            app.TiempoSlider.Limits = [3 5];
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @Start, true);
            app.TiempoSlider.Enable = 'off';
            app.TiempoSlider.Value = 3;
            app.UIAxes = uiaxes(app.SupervisorPanel);
            title(app.UIAxes, 'Posición')
            xlabel(app.UIAxes, 'Tiempo')
            ylabel(app.UIAxes, 'Posicion')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [0 5];
            app.ContraseaEditFieldLabel = uilabel(app.SupervisorPanel);
            app.ContraseaEditFieldLabel.Enable = 'off';
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.SupervisorPanel, 'text');
            app.ContraseaEditField.ValueChangedFcn = createCallbackFcn(app, @Start, true);
            app.ContraseaEditField.Enable = 'off';
            app.FormaButtonGroup = uibuttongroup(app.UIFigure);
            app.FormaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Start, true);
            app.FormaButtonGroup.Title = 'Forma';
            app.ManualButton = uiradiobutton(app.FormaButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.AutomaticoButton = uiradiobutton(app.FormaButtonGroup);
            app.AutomaticoButton.Text = 'Automatico';
            app.ObjetoButton = uiradiobutton(app.FormaButtonGroup);
            app.ObjetoButton.Text = 'Objeto';
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.Visible = 'off';
            app.Image4.ImageSource = 'peligrp.jpg';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Visible = 'off';
            app.EditField.Value = 'CUIDADO! OBJETO BLOQUEANDO';
            app.TiempodeesperaSpinnerLabel = uilabel(app.UIFigure);
            app.TiempodeesperaSpinnerLabel.Text = 'Tiempo de espera';
            app.TiempodeesperaSpinner = uispinner(app.UIFigure);
            app.TiempodeesperaSpinner.Limits = [0 Inf];
            app.TiempodeesperaSpinner.ValueChangedFcn = createCallbackFcn(app, @Start, true);
            app.TiempodeesperaSpinner.Enable = 'off';
            app.TiempodeesperaSpinner.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end